classdef NF8082StageControllerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Channel1Panel                  matlab.ui.container.Panel
        CurrentPositionEditFieldLabel  matlab.ui.control.Label
        CurrentPositionEditField       matlab.ui.control.NumericEditField
        DropDown                       matlab.ui.control.DropDown
        EditField                      matlab.ui.control.NumericEditField
        MovePanel                      matlab.ui.container.Panel
        GoButton                       matlab.ui.control.Button
        ForwardsEditField              matlab.ui.control.NumericEditField
        BackwardsEditField             matlab.ui.control.NumericEditField
        TotalEditFieldLabel            matlab.ui.control.Label
        TotalEditField                 matlab.ui.control.NumericEditField
        ofstepsEditFieldLabel          matlab.ui.control.Label
        ofstepsEditField               matlab.ui.control.NumericEditField
        GoButton_2                     matlab.ui.control.Button
        IgnoreCheckBox                 matlab.ui.control.CheckBox
        MaxstepsEditFieldLabel         matlab.ui.control.Label
        MaxstepsEditField              matlab.ui.control.NumericEditField
        ResetButton                    matlab.ui.control.Button
        DeactivateButton               matlab.ui.control.Button
        MotionStatusLampLabel          matlab.ui.control.Label
        MotionStatusLamp               matlab.ui.control.Lamp
        ConnectionStatusLampLabel      matlab.ui.control.Label
        ConnectionStatusLamp           matlab.ui.control.Lamp
        ZeroButton                     matlab.ui.control.Button
        SetButton                      matlab.ui.control.Button
        AbortallmotionButton           matlab.ui.control.Button
        Disconnect1Button              matlab.ui.control.Button
        RefreshGUIButton               matlab.ui.control.Button
        ReadyStatusLampLabel           matlab.ui.control.Label
        ReadyStatusLamp                matlab.ui.control.Lamp
        Channel2Panel                  matlab.ui.container.Panel
        CurrentPositionEditField_2Label  matlab.ui.control.Label
        CurrentPositionEditField_2     matlab.ui.control.NumericEditField
        DropDown_2                     matlab.ui.control.DropDown
        EditField_2                    matlab.ui.control.NumericEditField
        MovePanel_2                    matlab.ui.container.Panel
        GoButton_3                     matlab.ui.control.Button
        ForwardsEditField_2            matlab.ui.control.NumericEditField
        BackwardsEditField_2           matlab.ui.control.NumericEditField
        TotalEditField_2Label          matlab.ui.control.Label
        TotalEditField_2               matlab.ui.control.NumericEditField
        ofstepsEditField_2Label        matlab.ui.control.Label
        ofstepsEditField_2             matlab.ui.control.NumericEditField
        GoButton_4                     matlab.ui.control.Button
        IgnoreCheckBox_2               matlab.ui.control.CheckBox
        MaxstepsEditField_2Label       matlab.ui.control.Label
        MaxstepsEditField_2            matlab.ui.control.NumericEditField
        ResetButton_2                  matlab.ui.control.Button
        DeactivateButton_2             matlab.ui.control.Button
        MotionStatusLamp_2Label        matlab.ui.control.Label
        MotionStatusLamp_2             matlab.ui.control.Lamp
        ConnectionStatusLamp_2Label    matlab.ui.control.Label
        ConnectionStatusLamp_2         matlab.ui.control.Lamp
        ZeroButton_2                   matlab.ui.control.Button
        SetButton_2                    matlab.ui.control.Button
        Channel3Panel                  matlab.ui.container.Panel
        CurrentPositionEditField_3Label  matlab.ui.control.Label
        CurrentPositionEditField_3     matlab.ui.control.NumericEditField
        DropDown_3                     matlab.ui.control.DropDown
        EditField_3                    matlab.ui.control.NumericEditField
        MovePanel_3                    matlab.ui.container.Panel
        GoButton_5                     matlab.ui.control.Button
        ForwardsEditField_3            matlab.ui.control.NumericEditField
        BackwardsEditField_3           matlab.ui.control.NumericEditField
        TotalEditField_3Label          matlab.ui.control.Label
        TotalEditField_3               matlab.ui.control.NumericEditField
        ofstepsEditField_3Label        matlab.ui.control.Label
        ofstepsEditField_3             matlab.ui.control.NumericEditField
        GoButton_6                     matlab.ui.control.Button
        IgnoreCheckBox_3               matlab.ui.control.CheckBox
        MaxstepsEditField_3Label       matlab.ui.control.Label
        MaxstepsEditField_3            matlab.ui.control.NumericEditField
        ResetButton_3                  matlab.ui.control.Button
        DeactivateButton_3             matlab.ui.control.Button
        MotionStatusLamp_3Label        matlab.ui.control.Label
        MotionStatusLamp_3             matlab.ui.control.Lamp
        ConnectionStatusLamp_3Label    matlab.ui.control.Label
        ConnectionStatusLamp_3         matlab.ui.control.Lamp
        ZeroButton_3                   matlab.ui.control.Button
        SetButton_3                    matlab.ui.control.Button
        Channel4Panel                  matlab.ui.container.Panel
        CurrentPositionEditField_4Label  matlab.ui.control.Label
        CurrentPositionEditField_4     matlab.ui.control.NumericEditField
        DropDown_4                     matlab.ui.control.DropDown
        EditField_4                    matlab.ui.control.NumericEditField
        MovePanel_4                    matlab.ui.container.Panel
        GoButton_7                     matlab.ui.control.Button
        ForwardsEditField_4            matlab.ui.control.NumericEditField
        BackwardsEditField_4           matlab.ui.control.NumericEditField
        TotalEditField_4Label          matlab.ui.control.Label
        TotalEditField_4               matlab.ui.control.NumericEditField
        ofstepsEditField_4Label        matlab.ui.control.Label
        ofstepsEditField_4             matlab.ui.control.NumericEditField
        GoButton_8                     matlab.ui.control.Button
        IgnoreCheckBox_4               matlab.ui.control.CheckBox
        MaxstepsEditField_4Label       matlab.ui.control.Label
        MaxstepsEditField_4            matlab.ui.control.NumericEditField
        ResetButton_4                  matlab.ui.control.Button
        DeactivateButton_4             matlab.ui.control.Button
        MotionStatusLamp_4Label        matlab.ui.control.Label
        MotionStatusLamp_4             matlab.ui.control.Lamp
        ConnectionStatusLamp_4Label    matlab.ui.control.Label
        ConnectionStatusLamp_4         matlab.ui.control.Lamp
        ZeroButton_4                   matlab.ui.control.Button
        SetButton_4                    matlab.ui.control.Button
        Channel5Panel                  matlab.ui.container.Panel
        CurrentPositionEditField_5Label  matlab.ui.control.Label
        CurrentPositionEditField_5     matlab.ui.control.NumericEditField
        DropDown_5                     matlab.ui.control.DropDown
        EditField_5                    matlab.ui.control.NumericEditField
        MovePanel_5                    matlab.ui.container.Panel
        GoButton_9                     matlab.ui.control.Button
        ForwardsEditField_5            matlab.ui.control.NumericEditField
        BackwardsEditField_5           matlab.ui.control.NumericEditField
        TotalEditField_5Label          matlab.ui.control.Label
        TotalEditField_5               matlab.ui.control.NumericEditField
        ofstepsEditField_5Label        matlab.ui.control.Label
        ofstepsEditField_5             matlab.ui.control.NumericEditField
        GoButton_10                    matlab.ui.control.Button
        IgnoreCheckBox_5               matlab.ui.control.CheckBox
        MaxstepsEditField_5Label       matlab.ui.control.Label
        MaxstepsEditField_5            matlab.ui.control.NumericEditField
        ResetButton_5                  matlab.ui.control.Button
        DeactivateButton_5             matlab.ui.control.Button
        MotionStatusLamp_5Label        matlab.ui.control.Label
        MotionStatusLamp_5             matlab.ui.control.Lamp
        ConnectionStatusLamp_5Label    matlab.ui.control.Label
        ConnectionStatusLamp_5         matlab.ui.control.Lamp
        ZeroButton_5                   matlab.ui.control.Button
        SetButton_5                    matlab.ui.control.Button
        SaveHistoryButton              matlab.ui.control.Button
        CommandHistoryTextAreaLabel    matlab.ui.control.Label
        CommandHistoryTextArea         matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Disconnect1Button
        function Disconnect1ButtonValueChanged(app, event)
            str = get(app.Disconnect1Button,'Text');
            ind = find(strcmp(str,'Disconnect'));
            if ind == 1
                set(app.Disconnect1Button,'Text', 'Reconnect');
                set(app.Disconnect1Button,'BackgroundColor', 'red');
                set(app.Disconnect1Button,'FontColor', [0.96,0.96,0.96]);
            else
                set(app.Disconnect1Button,'Text', 'Disconnect');
                set(app.Disconnect1Button,'BackgroundColor', [0.96,0.96,0.96]);
                set(app.Disconnect1Button,'FontColor', [0,0,0]);
            end
        end

        % Value changed function: CurrentPositionEditField
        function CurrentPositionEditFieldValueChanged(app, event)
            value = app.CurrentPositionEditField.Value;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            
        end

        % Callback function: EditField, SetButton
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: ofstepsEditField
        function ofstepsEditFieldValueChanged(app, event)
            value = app.ofstepsEditField.Value;
            
        end

        % Value changed function: IgnoreCheckBox
        function IgnoreCheckBox_9ValueChanged(app, event)
            value = app.IgnoreCheckBox.Value;
            
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            
        end

        % Button pushed function: AbortallmotionButton
        function AbortallmotionButtonPushed(app, event)
            
        end

        % Value changed function: CommandHistoryTextArea
        function CommandHistoryTextAreaValueChanged(app, event)
            value = app.CommandHistoryTextArea.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Channel1Panel = uipanel(app.UIFigure);
            app.Channel1Panel.Title = 'Channel 1';
            app.CurrentPositionEditFieldLabel = uilabel(app.Channel1Panel);
            app.CurrentPositionEditFieldLabel.Text = 'Current Position';
            app.CurrentPositionEditField = uieditfield(app.Channel1Panel, 'numeric');
            app.CurrentPositionEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentPositionEditFieldValueChanged, true);
            app.DropDown = uidropdown(app.Channel1Panel);
            app.DropDown.Items = {'Velocity', 'Acceleration', 'Motor Type'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Velocity';
            app.EditField = uieditfield(app.Channel1Panel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.MovePanel = uipanel(app.Channel1Panel);
            app.MovePanel.Title = 'Move';
            app.GoButton = uibutton(app.MovePanel, 'push');
            app.GoButton.Text = 'Go';
            app.ForwardsEditField = uieditfield(app.MovePanel, 'numeric');
            app.BackwardsEditField = uieditfield(app.MovePanel, 'numeric');
            app.TotalEditFieldLabel = uilabel(app.MovePanel);
            app.TotalEditFieldLabel.Text = 'Total';
            app.TotalEditField = uieditfield(app.MovePanel, 'numeric');
            app.ofstepsEditFieldLabel = uilabel(app.MovePanel);
            app.ofstepsEditFieldLabel.Text = '# of steps';
            app.ofstepsEditField = uieditfield(app.MovePanel, 'numeric');
            app.ofstepsEditField.ValueChangedFcn = createCallbackFcn(app, @ofstepsEditFieldValueChanged, true);
            app.GoButton_2 = uibutton(app.MovePanel, 'push');
            app.GoButton_2.Text = 'Go';
            app.IgnoreCheckBox = uicheckbox(app.MovePanel);
            app.IgnoreCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoreCheckBox_9ValueChanged, true);
            app.IgnoreCheckBox.Text = 'Ignore';
            app.MaxstepsEditFieldLabel = uilabel(app.MovePanel);
            app.MaxstepsEditFieldLabel.Text = 'Max # steps';
            app.MaxstepsEditField = uieditfield(app.MovePanel, 'numeric');
            app.ResetButton = uibutton(app.MovePanel, 'push');
            app.ResetButton.Text = 'Reset #';
            app.DeactivateButton = uibutton(app.MovePanel, 'push');
            app.DeactivateButton.Text = 'Deactivate';
            app.MotionStatusLampLabel = uilabel(app.Channel1Panel);
            app.MotionStatusLampLabel.Text = 'Motion Status';
            app.MotionStatusLamp = uilamp(app.Channel1Panel);
            app.ConnectionStatusLampLabel = uilabel(app.Channel1Panel);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.Channel1Panel);
            app.ZeroButton = uibutton(app.Channel1Panel, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.SetButton = uibutton(app.Channel1Panel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.SetButton.Text = 'Set';
            app.AbortallmotionButton = uibutton(app.UIFigure, 'push');
            app.AbortallmotionButton.ButtonPushedFcn = createCallbackFcn(app, @AbortallmotionButtonPushed, true);
            app.AbortallmotionButton.Text = 'Abort all motion';
            app.Disconnect1Button = uibutton(app.UIFigure, 'push');
            app.Disconnect1Button.ButtonPushedFcn = createCallbackFcn(app, @Disconnect1ButtonValueChanged, true);
            app.Disconnect1Button.Text = 'Disconnect (1)';
            app.RefreshGUIButton = uibutton(app.UIFigure, 'push');
            app.RefreshGUIButton.Text = 'Refresh GUI';
            app.ReadyStatusLampLabel = uilabel(app.UIFigure);
            app.ReadyStatusLampLabel.Text = 'Ready Status';
            app.ReadyStatusLamp = uilamp(app.UIFigure);
            app.Channel2Panel = uipanel(app.UIFigure);
            app.Channel2Panel.Title = 'Channel 2';
            app.CurrentPositionEditField_2Label = uilabel(app.Channel2Panel);
            app.CurrentPositionEditField_2Label.Text = 'Current Position';
            app.CurrentPositionEditField_2 = uieditfield(app.Channel2Panel, 'numeric');
            app.DropDown_2 = uidropdown(app.Channel2Panel);
            app.DropDown_2.Items = {'Velocity', 'Acceleration', 'Motor Type'};
            app.DropDown_2.Value = 'Velocity';
            app.EditField_2 = uieditfield(app.Channel2Panel, 'numeric');
            app.MovePanel_2 = uipanel(app.Channel2Panel);
            app.MovePanel_2.Title = 'Move';
            app.GoButton_3 = uibutton(app.MovePanel_2, 'push');
            app.GoButton_3.Text = 'Go';
            app.ForwardsEditField_2 = uieditfield(app.MovePanel_2, 'numeric');
            app.BackwardsEditField_2 = uieditfield(app.MovePanel_2, 'numeric');
            app.TotalEditField_2Label = uilabel(app.MovePanel_2);
            app.TotalEditField_2Label.Text = 'Total';
            app.TotalEditField_2 = uieditfield(app.MovePanel_2, 'numeric');
            app.ofstepsEditField_2Label = uilabel(app.MovePanel_2);
            app.ofstepsEditField_2Label.Text = '# of steps';
            app.ofstepsEditField_2 = uieditfield(app.MovePanel_2, 'numeric');
            app.GoButton_4 = uibutton(app.MovePanel_2, 'push');
            app.GoButton_4.Text = 'Go';
            app.IgnoreCheckBox_2 = uicheckbox(app.MovePanel_2);
            app.IgnoreCheckBox_2.Text = 'Ignore';
            app.MaxstepsEditField_2Label = uilabel(app.MovePanel_2);
            app.MaxstepsEditField_2Label.Text = 'Max # steps';
            app.MaxstepsEditField_2 = uieditfield(app.MovePanel_2, 'numeric');
            app.ResetButton_2 = uibutton(app.MovePanel_2, 'push');
            app.ResetButton_2.Text = 'Reset #';
            app.DeactivateButton_2 = uibutton(app.MovePanel_2, 'push');
            app.DeactivateButton_2.Text = 'Deactivate';
            app.MotionStatusLamp_2Label = uilabel(app.Channel2Panel);
            app.MotionStatusLamp_2Label.Text = 'Motion Status';
            app.MotionStatusLamp_2 = uilamp(app.Channel2Panel);
            app.ConnectionStatusLamp_2Label = uilabel(app.Channel2Panel);
            app.ConnectionStatusLamp_2Label.Text = 'Connection Status';
            app.ConnectionStatusLamp_2 = uilamp(app.Channel2Panel);
            app.ZeroButton_2 = uibutton(app.Channel2Panel, 'push');
            app.ZeroButton_2.Text = 'Zero';
            app.SetButton_2 = uibutton(app.Channel2Panel, 'push');
            app.SetButton_2.Text = 'Set';
            app.Channel3Panel = uipanel(app.UIFigure);
            app.Channel3Panel.Title = 'Channel 3';
            app.CurrentPositionEditField_3Label = uilabel(app.Channel3Panel);
            app.CurrentPositionEditField_3Label.Text = 'Current Position';
            app.CurrentPositionEditField_3 = uieditfield(app.Channel3Panel, 'numeric');
            app.DropDown_3 = uidropdown(app.Channel3Panel);
            app.DropDown_3.Items = {'Velocity', 'Acceleration', 'Motor Type'};
            app.DropDown_3.Value = 'Velocity';
            app.EditField_3 = uieditfield(app.Channel3Panel, 'numeric');
            app.MovePanel_3 = uipanel(app.Channel3Panel);
            app.MovePanel_3.Title = 'Move';
            app.GoButton_5 = uibutton(app.MovePanel_3, 'push');
            app.GoButton_5.Text = 'Go';
            app.ForwardsEditField_3 = uieditfield(app.MovePanel_3, 'numeric');
            app.BackwardsEditField_3 = uieditfield(app.MovePanel_3, 'numeric');
            app.TotalEditField_3Label = uilabel(app.MovePanel_3);
            app.TotalEditField_3Label.Text = 'Total';
            app.TotalEditField_3 = uieditfield(app.MovePanel_3, 'numeric');
            app.ofstepsEditField_3Label = uilabel(app.MovePanel_3);
            app.ofstepsEditField_3Label.Text = '# of steps';
            app.ofstepsEditField_3 = uieditfield(app.MovePanel_3, 'numeric');
            app.GoButton_6 = uibutton(app.MovePanel_3, 'push');
            app.GoButton_6.Text = 'Go';
            app.IgnoreCheckBox_3 = uicheckbox(app.MovePanel_3);
            app.IgnoreCheckBox_3.Text = 'Ignore';
            app.MaxstepsEditField_3Label = uilabel(app.MovePanel_3);
            app.MaxstepsEditField_3Label.Text = 'Max # steps';
            app.MaxstepsEditField_3 = uieditfield(app.MovePanel_3, 'numeric');
            app.ResetButton_3 = uibutton(app.MovePanel_3, 'push');
            app.ResetButton_3.Text = 'Reset #';
            app.DeactivateButton_3 = uibutton(app.MovePanel_3, 'push');
            app.DeactivateButton_3.Text = 'Deactivate';
            app.MotionStatusLamp_3Label = uilabel(app.Channel3Panel);
            app.MotionStatusLamp_3Label.Text = 'Motion Status';
            app.MotionStatusLamp_3 = uilamp(app.Channel3Panel);
            app.ConnectionStatusLamp_3Label = uilabel(app.Channel3Panel);
            app.ConnectionStatusLamp_3Label.Text = 'Connection Status';
            app.ConnectionStatusLamp_3 = uilamp(app.Channel3Panel);
            app.ZeroButton_3 = uibutton(app.Channel3Panel, 'push');
            app.ZeroButton_3.Text = 'Zero';
            app.SetButton_3 = uibutton(app.Channel3Panel, 'push');
            app.SetButton_3.Text = 'Set';
            app.Channel4Panel = uipanel(app.UIFigure);
            app.Channel4Panel.Title = 'Channel 4';
            app.CurrentPositionEditField_4Label = uilabel(app.Channel4Panel);
            app.CurrentPositionEditField_4Label.Text = 'Current Position';
            app.CurrentPositionEditField_4 = uieditfield(app.Channel4Panel, 'numeric');
            app.DropDown_4 = uidropdown(app.Channel4Panel);
            app.DropDown_4.Items = {'Velocity', 'Acceleration', 'Motor Type'};
            app.DropDown_4.Value = 'Velocity';
            app.EditField_4 = uieditfield(app.Channel4Panel, 'numeric');
            app.MovePanel_4 = uipanel(app.Channel4Panel);
            app.MovePanel_4.Title = 'Move';
            app.GoButton_7 = uibutton(app.MovePanel_4, 'push');
            app.GoButton_7.Text = 'Go';
            app.ForwardsEditField_4 = uieditfield(app.MovePanel_4, 'numeric');
            app.BackwardsEditField_4 = uieditfield(app.MovePanel_4, 'numeric');
            app.TotalEditField_4Label = uilabel(app.MovePanel_4);
            app.TotalEditField_4Label.Text = 'Total';
            app.TotalEditField_4 = uieditfield(app.MovePanel_4, 'numeric');
            app.ofstepsEditField_4Label = uilabel(app.MovePanel_4);
            app.ofstepsEditField_4Label.Text = '# of steps';
            app.ofstepsEditField_4 = uieditfield(app.MovePanel_4, 'numeric');
            app.GoButton_8 = uibutton(app.MovePanel_4, 'push');
            app.GoButton_8.Text = 'Go';
            app.IgnoreCheckBox_4 = uicheckbox(app.MovePanel_4);
            app.IgnoreCheckBox_4.Text = 'Ignore';
            app.MaxstepsEditField_4Label = uilabel(app.MovePanel_4);
            app.MaxstepsEditField_4Label.Text = 'Max # steps';
            app.MaxstepsEditField_4 = uieditfield(app.MovePanel_4, 'numeric');
            app.ResetButton_4 = uibutton(app.MovePanel_4, 'push');
            app.ResetButton_4.Text = 'Reset #';
            app.DeactivateButton_4 = uibutton(app.MovePanel_4, 'push');
            app.DeactivateButton_4.Text = 'Deactivate';
            app.MotionStatusLamp_4Label = uilabel(app.Channel4Panel);
            app.MotionStatusLamp_4Label.Text = 'Motion Status';
            app.MotionStatusLamp_4 = uilamp(app.Channel4Panel);
            app.ConnectionStatusLamp_4Label = uilabel(app.Channel4Panel);
            app.ConnectionStatusLamp_4Label.Text = 'Connection Status';
            app.ConnectionStatusLamp_4 = uilamp(app.Channel4Panel);
            app.ZeroButton_4 = uibutton(app.Channel4Panel, 'push');
            app.ZeroButton_4.Text = 'Zero';
            app.SetButton_4 = uibutton(app.Channel4Panel, 'push');
            app.SetButton_4.Text = 'Set';
            app.Channel5Panel = uipanel(app.UIFigure);
            app.Channel5Panel.Title = 'Channel 5';
            app.CurrentPositionEditField_5Label = uilabel(app.Channel5Panel);
            app.CurrentPositionEditField_5Label.Text = 'Current Position';
            app.CurrentPositionEditField_5 = uieditfield(app.Channel5Panel, 'numeric');
            app.DropDown_5 = uidropdown(app.Channel5Panel);
            app.DropDown_5.Items = {'Velocity', 'Acceleration', 'Motor Type'};
            app.DropDown_5.Value = 'Velocity';
            app.EditField_5 = uieditfield(app.Channel5Panel, 'numeric');
            app.MovePanel_5 = uipanel(app.Channel5Panel);
            app.MovePanel_5.Title = 'Move';
            app.GoButton_9 = uibutton(app.MovePanel_5, 'push');
            app.GoButton_9.Text = 'Go';
            app.ForwardsEditField_5 = uieditfield(app.MovePanel_5, 'numeric');
            app.BackwardsEditField_5 = uieditfield(app.MovePanel_5, 'numeric');
            app.TotalEditField_5Label = uilabel(app.MovePanel_5);
            app.TotalEditField_5Label.Text = 'Total';
            app.TotalEditField_5 = uieditfield(app.MovePanel_5, 'numeric');
            app.ofstepsEditField_5Label = uilabel(app.MovePanel_5);
            app.ofstepsEditField_5Label.Text = '# of steps';
            app.ofstepsEditField_5 = uieditfield(app.MovePanel_5, 'numeric');
            app.GoButton_10 = uibutton(app.MovePanel_5, 'push');
            app.GoButton_10.Text = 'Go';
            app.IgnoreCheckBox_5 = uicheckbox(app.MovePanel_5);
            app.IgnoreCheckBox_5.Text = 'Ignore';
            app.MaxstepsEditField_5Label = uilabel(app.MovePanel_5);
            app.MaxstepsEditField_5Label.Text = 'Max # steps';
            app.MaxstepsEditField_5 = uieditfield(app.MovePanel_5, 'numeric');
            app.ResetButton_5 = uibutton(app.MovePanel_5, 'push');
            app.ResetButton_5.Text = 'Reset #';
            app.DeactivateButton_5 = uibutton(app.MovePanel_5, 'push');
            app.DeactivateButton_5.Text = 'Deactivate';
            app.MotionStatusLamp_5Label = uilabel(app.Channel5Panel);
            app.MotionStatusLamp_5Label.Text = 'Motion Status';
            app.MotionStatusLamp_5 = uilamp(app.Channel5Panel);
            app.ConnectionStatusLamp_5Label = uilabel(app.Channel5Panel);
            app.ConnectionStatusLamp_5Label.Text = 'Connection Status';
            app.ConnectionStatusLamp_5 = uilamp(app.Channel5Panel);
            app.ZeroButton_5 = uibutton(app.Channel5Panel, 'push');
            app.ZeroButton_5.Text = 'Zero';
            app.SetButton_5 = uibutton(app.Channel5Panel, 'push');
            app.SetButton_5.Text = 'Set';
            app.SaveHistoryButton = uibutton(app.UIFigure, 'push');
            app.SaveHistoryButton.Text = 'Save History';
            app.CommandHistoryTextAreaLabel = uilabel(app.UIFigure);
            app.CommandHistoryTextAreaLabel.Text = 'Command History';
            app.CommandHistoryTextArea = uitextarea(app.UIFigure);
            app.CommandHistoryTextArea.ValueChangedFcn = createCallbackFcn(app, @CommandHistoryTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NF8082StageControllerGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end